classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ScenarioTab                     matlab.ui.container.Tab
        ScenarioListBoxLabel            matlab.ui.control.Label
        ScenarioListBox                 matlab.ui.control.ListBox
        PreProcessingTab                matlab.ui.container.Tab
        NormalizeButtonGroup            matlab.ui.container.ButtonGroup
        YesButton                       matlab.ui.control.RadioButton
        NoButton                        matlab.ui.control.RadioButton
        DatasetListBoxLabel             matlab.ui.control.Label
        DatasetListBox                  matlab.ui.control.ListBox
        FeatureSelectionTab             matlab.ui.container.Tab
        ThresholdKruskalEditFieldLabel  matlab.ui.control.Label
        ThresholdKruskalEditField       matlab.ui.control.NumericEditField
        MethodsButtonGroup              matlab.ui.container.ButtonGroup
        KruskalTestButton_2             matlab.ui.control.StateButton
        CorrelationCovarianceButton_2   matlab.ui.control.StateButton
        ThresholdCorrelationEditFieldLabel  matlab.ui.control.Label
        ThresholdCorrelationEditField   matlab.ui.control.NumericEditField
        FeatureReductionTab             matlab.ui.container.Tab
        ReductionMethodDropDownLabel    matlab.ui.control.Label
        ReductionMethodDropDown         matlab.ui.control.DropDown
        CriteriaListBoxLabel            matlab.ui.control.Label
        CriteriaListBox                 matlab.ui.control.ListBox
        ThresholdEditFieldLabel         matlab.ui.control.Label
        ThresholdEditField              matlab.ui.control.NumericEditField
        ClassifyTab                     matlab.ui.container.Tab
        ClassifierListBoxLabel          matlab.ui.control.Label
        ClassifierListBox               matlab.ui.control.ListBox
        ClassifyButton                  matlab.ui.control.Button
    end


    properties (Access = public)
        scenario = "A"
        dataset = "hue";
        normalize = "Yes";
        selection  = ["empty", "empty"];
        thresholdKruskal = 0.7;
        thresholdCorrelation = 0.8;
        reduction = "PCA";
        splitting = 0.7;
        classifier = "Euclidean Linear Discriminant";
        criteria = "Kaiser"
        criteriaThreshold = 0.9
    end


    methods (Access = private)

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)

            main(app.scenario, app.dataset,app.normalize, app.selection,app.thresholdKruskal, app.thresholdCorrelation, app.reduction,app.classifier, app.criteria, app.criteriaThreshold);
        end

        % Callback function
        function DatasetPathEditFieldValueChanged(app, event)

        end

        % Callback function
        function NormalizeButtonGroupSelectionChanged(app, event)
            
        end

        % Callback function
        function SelectionMethodDropDownValueChanged(app, event)
         
        end

        % Value changed function: ThresholdKruskalEditField
        function ThresholdKruskalEditFieldValueChanged(app, event)
            app.thresholdKruskal = app.ThresholdKruskalEditField.Value;
        end

        % Value changed function: ReductionMethodDropDown
        function ReductionMethodDropDownValueChanged(app, event)
            app.reduction = app.ReductionMethodDropDown.Value;
        end

        % Callback function
        function SplittingEditFieldValueChanged(app, event)
            app.splitting = app.SplittingEditField.Value;
        end

        % Value changed function: ClassifierListBox
        function ClassifierListBoxValueChanged(app, event)
            app.classifier = app.ClassifierListBox.Value;
        end

        % Value changed function: DatasetListBox
        function DatasetListBoxValueChanged(app, event)
            app.dataset = lower(app.DatasetListBox.Value);
        end

        % Value changed function: KruskalTestButton_2
        function KruskalTestButton_2ValueChanged(app, event)
            value = app.KruskalTestButton_2.Value;
            if(value == 1)
                app.selection(1,1) = "Kruskal-Test";
            end
        end

        % Value changed function: CorrelationCovarianceButton_2
        function CorrelationCovarianceButton_2ValueChanged(app, event)
            value = app.CorrelationCovarianceButton_2.Value;
            if(value == 1)
                app.selection(1,1) = "Correlation & Co-variance";
            end
        end

        % Value changed function: ThresholdCorrelationEditField
        function ThresholdCorrelationEditFieldValueChanged(app, event)
            app.thresholdCorrelation = app.ThresholdCorrelationEditField.Value;
            
        end

        % Selection changed function: NormalizeButtonGroup
        function NormalizeButtonGroupSelectionChanged2(app, event)
            app.normalize = app.NormalizeButtonGroup.SelectedObject;
            
        end

        % Value changed function: ScenarioListBox
        function ScenarioListBoxValueChanged(app, event)
            app.scenario = app.ScenarioListBox.Value;
            
        end

        % Value changed function: CriteriaListBox
        function CriteriaListBoxValueChanged(app, event)
            app.criteria = app.CriteriaListBox.Value;
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            app.criteriaThreshold = app.ThresholdEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ScenarioTab = uitab(app.TabGroup);
            app.ScenarioTab.Title = 'Scenario';
            app.ScenarioListBoxLabel = uilabel(app.ScenarioTab);
            app.ScenarioListBoxLabel.Text = 'Scenario';
            app.ScenarioListBox = uilistbox(app.ScenarioTab);
            app.ScenarioListBox.Items = {'A', 'B', 'C'};
            app.ScenarioListBox.ValueChangedFcn = createCallbackFcn(app, @ScenarioListBoxValueChanged, true);
            app.ScenarioListBox.Value = 'A';
            app.PreProcessingTab = uitab(app.TabGroup);
            app.PreProcessingTab.Title = 'Pre-Processing';
            app.NormalizeButtonGroup = uibuttongroup(app.PreProcessingTab);
            app.NormalizeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @NormalizeButtonGroupSelectionChanged2, true);
            app.NormalizeButtonGroup.Title = 'Normalize?';
            app.YesButton = uiradiobutton(app.NormalizeButtonGroup);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.NormalizeButtonGroup);
            app.NoButton.Text = 'No';
            app.DatasetListBoxLabel = uilabel(app.PreProcessingTab);
            app.DatasetListBoxLabel.Text = 'Dataset';
            app.DatasetListBox = uilistbox(app.PreProcessingTab);
            app.DatasetListBox.Items = {'Hue', 'Haar', 'HOG1', 'HOG2', 'HOG3'};
            app.DatasetListBox.ValueChangedFcn = createCallbackFcn(app, @DatasetListBoxValueChanged, true);
            app.DatasetListBox.Value = 'Hue';
            app.FeatureSelectionTab = uitab(app.TabGroup);
            app.FeatureSelectionTab.Title = 'Feature Selection';
            app.ThresholdKruskalEditFieldLabel = uilabel(app.FeatureSelectionTab);
            app.ThresholdKruskalEditFieldLabel.Text = 'Threshold Kruskal';
            app.ThresholdKruskalEditField = uieditfield(app.FeatureSelectionTab, 'numeric');
            app.ThresholdKruskalEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdKruskalEditFieldValueChanged, true);
            app.ThresholdKruskalEditField.Value = 0.7;
            app.MethodsButtonGroup = uibuttongroup(app.FeatureSelectionTab);
            app.MethodsButtonGroup.Title = 'Methods';
            app.KruskalTestButton_2 = uibutton(app.MethodsButtonGroup, 'state');
            app.KruskalTestButton_2.ValueChangedFcn = createCallbackFcn(app, @KruskalTestButton_2ValueChanged, true);
            app.KruskalTestButton_2.Text = 'Kruskal-Test';
            app.CorrelationCovarianceButton_2 = uibutton(app.MethodsButtonGroup, 'state');
            app.CorrelationCovarianceButton_2.ValueChangedFcn = createCallbackFcn(app, @CorrelationCovarianceButton_2ValueChanged, true);
            app.CorrelationCovarianceButton_2.Text = 'Correlation & Co-variance';
            app.ThresholdCorrelationEditFieldLabel = uilabel(app.FeatureSelectionTab);
            app.ThresholdCorrelationEditFieldLabel.Text = 'Threshold Correlation';
            app.ThresholdCorrelationEditField = uieditfield(app.FeatureSelectionTab, 'numeric');
            app.ThresholdCorrelationEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdCorrelationEditFieldValueChanged, true);
            app.ThresholdCorrelationEditField.Value = 0.8;
            app.FeatureReductionTab = uitab(app.TabGroup);
            app.FeatureReductionTab.Title = 'Feature Reduction';
            app.ReductionMethodDropDownLabel = uilabel(app.FeatureReductionTab);
            app.ReductionMethodDropDownLabel.Text = 'Reduction Method';
            app.ReductionMethodDropDown = uidropdown(app.FeatureReductionTab);
            app.ReductionMethodDropDown.Items = {'PCA', 'LDA', 'PCA & LDA', 'LDA & PCA'};
            app.ReductionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @ReductionMethodDropDownValueChanged, true);
            app.ReductionMethodDropDown.Value = 'PCA';
            app.CriteriaListBoxLabel = uilabel(app.FeatureReductionTab);
            app.CriteriaListBoxLabel.Text = 'Criteria';
            app.CriteriaListBox = uilistbox(app.FeatureReductionTab);
            app.CriteriaListBox.Items = {'Kaiser', 'Scree', 'None'};
            app.CriteriaListBox.ValueChangedFcn = createCallbackFcn(app, @CriteriaListBoxValueChanged, true);
            app.CriteriaListBox.Value = 'Kaiser';
            app.ThresholdEditFieldLabel = uilabel(app.FeatureReductionTab);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.FeatureReductionTab, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.9;
            app.ClassifyTab = uitab(app.TabGroup);
            app.ClassifyTab.Title = 'Classify';
            app.ClassifierListBoxLabel = uilabel(app.ClassifyTab);
            app.ClassifierListBoxLabel.Text = 'Classifier';
            app.ClassifierListBox = uilistbox(app.ClassifyTab);
            app.ClassifierListBox.Items = {'Euclidean Linear Discriminant', 'Mahalanobis Linear Discriminant', 'Linear Discriminant', 'Decision Tree', 'KNN', 'SVM'};
            app.ClassifierListBox.ValueChangedFcn = createCallbackFcn(app, @ClassifierListBoxValueChanged, true);
            app.ClassifierListBox.Value = 'Euclidean Linear Discriminant';
            app.ClassifyButton = uibutton(app.ClassifyTab, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify!';
        end
    end

    methods (Access = public)

        % Construct app
        function app = gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end